classdef Tire_Filter_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        AuswahlPanel                  matlab.ui.container.Panel
        LoadTireDataButton            matlab.ui.control.Button
        Test_file_directroy           matlab.ui.control.EditField
        OpenDirectoryButton           matlab.ui.control.Button
        Panel                         matlab.ui.container.Panel
        ReifentestViewerCarNo62Label  matlab.ui.control.Label
        DynamicseVLabel               matlab.ui.control.Label
        TabGroup2                     matlab.ui.container.TabGroup
        TestPreprocessingTab          matlab.ui.container.Tab
        UIAxes_Sweep                  matlab.ui.control.UIAxes
        UIAxes_Mu                     matlab.ui.control.UIAxes
        UIAxes_Pneu                   matlab.ui.control.UIAxes
        CorneringTab_2                matlab.ui.container.Tab
        CorneringKennwerteTab_2       matlab.ui.container.Tab
        UIAxes_CK_1                   matlab.ui.control.UIAxes
        UIAxes_CK_2                   matlab.ui.control.UIAxes
        UIAxes_CK_3                   matlab.ui.control.UIAxes
        CamberSweepTab_2              matlab.ui.container.Tab
        UIAxes_Cam_1                  matlab.ui.control.UIAxes
        UIAxes_Cam_2                  matlab.ui.control.UIAxes
        UIAxes_Cam_3                  matlab.ui.control.UIAxes
        DriveBrakeTab_2               matlab.ui.container.Tab
        UIAxes_DB_1                   matlab.ui.control.UIAxes
        UIAxes_DB_2                   matlab.ui.control.UIAxes
        UIAxes_DB_3                   matlab.ui.control.UIAxes
        CombinedSlipTab_2             matlab.ui.container.Tab
        UIAxes_CS_1                   matlab.ui.control.UIAxes
        UIAxes_CS_2                   matlab.ui.control.UIAxes
        ColdtohotVerschleissTab       matlab.ui.container.Tab
        UIAxes_CH_1                   matlab.ui.control.UIAxes
        UIAxes_CH_2                   matlab.ui.control.UIAxes
        TransientTab_2                matlab.ui.container.Tab
        UIAxes_TR_1                   matlab.ui.control.UIAxes
        UIAxes_TR_2                   matlab.ui.control.UIAxes
        SpeedVergleichTab_2           matlab.ui.container.Tab
        UIAxes_SV_1                   matlab.ui.control.UIAxes
        UIAxes_SV_2                   matlab.ui.control.UIAxes
        UIAxes_SV_3                   matlab.ui.control.UIAxes
        RohdatenExplorerTab_2         matlab.ui.container.Tab
        UIAxes_RE_1                   matlab.ui.control.UIAxes
        UIAxes_RE_2                   matlab.ui.control.UIAxes
        ManuelSelctionTab             matlab.ui.container.Tab
        ShowFilteredYellowCheckBox    matlab.ui.control.CheckBox
        UIAxes_MS_1                   matlab.ui.control.UIAxes
        UIAxes_MS_2                   matlab.ui.control.UIAxes
        UIAxes_MS_3                   matlab.ui.control.UIAxes
        Tab                           matlab.ui.container.Tab
    end


    %% Property Definitionene

    % Propertys für Backend
    properties (Access = private)
        % Klassifikation
        SA_THRESH_DEG (1,1) double = 0.5    % [deg] ab wann SA "aktiv"
        SL_THRESH (1,1) double = 0.02       % [-] ab wann SL "aktiv"
        FILTER_WIN (1,1) double = 301       % Medianfilter-Fenster fuer Klassifikation

        % Qualitaetsfilter
        MIN_POINTS (1,1) double = 50        % Mindest-Datenpunkte pro Segment
        TEMP_MIN (1,1) double = 40          % [°C] Mindesttemperatur
        FZ_MIN (1,1) double = 50            % [N] Kein Reifenkontakt
        SA_COVERAGE_DEG (1,1) double = 7.0  % [deg] Lateral-Coverage
        SL_COVERAGE (1,1) double = 0.06     % [-] Long/Combined-Coverage

        % Daten
        sweep
        tire_file
        FilterRows = struct('Parameter', {}, 'ParameterLabel', {}, 'RowLayout', {}, 'ConditionDropDown', {}, 'Value1EditField', {}, 'Value2EditField', {}, 'DeleteButton', {})
        SmoothingRows = struct('Parameter', {}, 'RowLayout', {}, 'TypeDropDown', {}, 'EnabledCheckBox', {}, 'Param1Label', {}, 'Param1EditField', {}, 'Param2Label', {}, 'Param2EditField', {}, 'DeleteButton', {})

        % Nutzungs-Masken (je ein logical-Array, gleiche Groesse wie app.sweep)
        SweepIsUsed logical = []      % EFFEKTIVE Maske (Filter & ~Manuell) - wird von allen Plots konsultiert
        FilterIsUsed logical = []     % Ergebnis der reinen Bounds-Filterung (ohne manuellen Ausschluss)
        ManualExclude logical = []    % Manuell (per Klick im Sweep-Auswahl-Tab) ausgeschlossene Sweeps

        % Reifen daten nach denen gefiltert werden kann
        KNOWN_CHANNELS cell = {'alpha', 'kappa', 'Fx', 'Fy', 'Fz', 'Mx', 'Mz', 'IP', 'gamma', 'V', 'omega', 'TtreadI', 'TtreadC', 'TtreadO'} % Bekannte Messkanaele, unabhaengig vom Ladezustand

        % Smothings die gemacht werden koennen
        SMOOTHING_TYPES cell = {'Bessel', 'Moving Average', 'Savitzky-Golay', 'Median'}
    end

    % Properties for Java Scripts
    properties (Access = public)
        StartupHTML                   matlab.ui.control.HTML
        LogoTopRight                  matlab.ui.control.Image

        UIFigure_html                 matlab.ui.Figure
        FilterSmoothingHTML           matlab.ui.control.HTML
        CorneringHTML                  matlab.ui.control.HTML
    end

    % Alle Eigenen functions
    methods (Access = private)
        %% Reifenobjecte
        
        function load_Tire_Data(app)

            % Laden des Dateipfads aus dem Textfeld
            tire_file_full = string(app.Test_file_directroy.Value);
            tire_file_full = strip(tire_file_full, 'both', "'");
            tire_data_raw = load(tire_file_full);

            % tireData-Objekt erstellen und befüllen
            td_raw = create_Tire_object(app);
            td_raw = populate_tire_object(app, td_raw, tire_data_raw);
            segs = split(td_raw, "et");
            segs = setTestingMethod_Smoothed(app, segs, deg2rad(app.SA_THRESH_DEG), app.SL_THRESH, app.FILTER_WIN);

            % Abfertigen der Sweeps
            app.sweep = segs;
        end

        function [tdnew] = create_Tire_object(app)
            % Erstellt ein tireData Object im SAE Koordinaten system

            tdnew = tireData();                         % Anlegen des Objects
            tdnew = tdnew.coordinateTransform("SAE");   % Definition des Koordinaten systems, später überschrieben

        end

        function [tdnew] = populate_tire_object(app, tdnew, fsData)
            %% Eingangsvariabeln
            % tdnew ist ein leeres tireData Object
            % fsData ist ein befülltest testdaten struc

            %% Ausgangsvariabeln
            % tdnew ist ein befülltes tiredata object

            %% Function Code
            % Preperation
            [row, ~] = size(fsData.MX);

            % Messdaten und Achsen (Umrechnung in SI-Einheiten) ---
            tdnew.et                = fsData.ET;                        % Zeit [s]
            tdnew.seget             = fsData.ET;                        % Segmentzeit [s]
            tdnew.segment           = ones(row, 1);                     % Segment-ID (Platzhalter: 1)
            tdnew.measnumb          = linspace(1, row, row);            % Messpunktnummer
            tdnew.Fx                = fsData.FX;                        % Längskraft [N]
            tdnew.Fy                = fsData.FY;                        % Seitenkraft [N]
            tdnew.Fz                = fsData.FZ;                        % Normalkraft [N]
            tdnew.Mx                = fsData.MX;                        % Überrollmoment (Mx) [Nm]
            tdnew.My                = zeros(row, 1);                    % Kippmoment (My) [Nm] (Zero-Placeholder)
            tdnew.Mz                = fsData.MZ;                        % Ausrichtendes Moment (Mz) [Nm]
            tdnew.IP                = fsData.P * 1000;                  % Inflationsdruck [Pa] (von kPa)
            tdnew.alpha             = (pi/180) * fsData.SA;             % Schräglaufwinkel [rad] (von deg)
            tdnew.gamma             = (pi/180) * fsData.IA;             % Sturzwinkel [rad] (von deg)
            tdnew.kappa             = fsData.SL;                        % Längsschlupf [-]
            tdnew.phit              = zeros(row, 1);                    % Wegrollwinkel [rad] (Zero-Placeholder)
            tdnew.V                 = (1000 / 3600) * (fsData.V);       % Geschwindigkeit [m/s] (von km/h)
            tdnew.omega             = (1 / 60) * (fsData.N);            % Radwinkelgeschw. [U/s] (von U/min)
            tdnew.TtreadI           = fsData.TSTI;                      % Reifen Temperatur Inner in °C
            tdnew.TtreadC           = fsData.TSTC;                      % Reifen Temperatur Center in °C
            tdnew.TtreadO           = fsData.TSTO;                      % Reifen Temperatur Outer in °C

            % Statische Metadaten (Reifendimensionen und Testbedingungen) ---
            tdnew.Comments          = fsData.tireid;                      % Name des Reifens zur dateibenneung
            tdnew.TestMethod        = fsData.testid;                    % Testtyp (Wichtig für Fx/Fy-Trennung)
            tdnew.TireSize          = "152.4/67R10";                    % Reifengröße, (Technisch nicht relevant, platzhalter stehen gelassen)
            tdnew.SectionWidth      = 152.4000;                         % Schnittbreite [mm]
            tdnew.AspectRatio       = 67;                               % Querschnittsverhältnis [%]
            tdnew.RimDiameter       = 10;                               % Felgendurchmesser [inch]
            tdnew.OverallDiameter   = 0.472;                            % Gesamtdurchmesser [m]
            tdnew.LoadIndex         = 90;                               % Lastindex
            tdnew.SpeedSymbol       = "V";                              % Geschwindigkeitssymbol
            tdnew.TestFacility      = "Dynamics e.V.";                  % Testeinrichtung (Metadaten)
            tdnew.TestMachine       = "MTS Flat-Trac LTRe";             % Testmaschine (Metadaten)
            tdnew.RimWidth          = 7;                                % Felgenbreite [inch]
            tdnew.Surface           = "120 3Mite";                      % Oberflächentyp
            tdnew.SurfaceCondition  = "Dry";                            % Oberflächenzustand
            tdnew.TestDate          = "24-Apr-2020 14:55:29";           % Testdatum/Zeit
            tdnew                   = tdnew.coordinateTransform("ISO"); % Konvertierung zu ISO-Standardachse
        end

        function tireObj_out = setTestingMethod_Smoothed(app, tireObj_in, threshold_SA, threshold_SL, filter_window)
            % SETTESTINGMETHOD_SMOOTHED Bestimmt die TestingMethod basierend auf dem dominierenden
            %                            glatten Klassifizierungs-Ergebnis der Zeitreihe.
            %
            % Autor: Lambo || Datum: 17.04.26
            %
            % EINGABE:
            %   tireObj_in: Ein tireData-Objekt oder Array von Objekten.
            %   threshold_SA: Schwellenwert für Schlupfwinkel (z.B. 0.5 deg).
            %   threshold_SL: Schwellenwert für Längsschlupf (z.B. 0.02).
            %   filter_window: Fenstergröße für den Medianfilter (Muss ungerade sein).

            % 1. Standard-Werte und Konstanten
            if nargin < 4
                filter_window = 301;
            end
            if nargin < 3
                threshold_SL = 0.02;
            end
            if nargin < 2
                threshold_SA = 0.5;
            end

            % Stelle sicher, dass das Filterfenster ungerade ist
            if mod(filter_window, 2) == 0
                filter_window = filter_window + 1;
            end

            % Definitionen (müssen mit dem Visualisierungs-Skript übereinstimmen)
            TYPE_INAKTIV = 0;
            TYPE_LATERAL = 1;
            TYPE_LONGITUDINAL = 2;
            TYPE_COMBINED = 3;

            tireObj_out = tireObj_in; % Kopie des Eingabeobjekts erstellen

            % 2. Hauptschleife über alle Objekte
            for k = 1:length(tireObj_in)
                current_obj = tireObj_in(k);

                % Daten extrahieren: Korrektur auf 'alpha' und 'kappa' ---
                SA_data = current_obj.alpha;
                SL_data = current_obj.kappa;

                % Vektoren synchronisieren
                minLen = min(length(SA_data), length(SL_data));
                SA_data = SA_data(1:minLen);
                SL_data = SL_data(1:minLen);
                numPoints = minLen;

                classificationVector = zeros(numPoints, 1);

                % 3. Punktweise Klassifizierung ---
                for p = 1:numPoints
                    sa_active = abs(SA_data(p)) > threshold_SA;
                    sl_active = abs(SL_data(p)) > threshold_SL;

                    if sa_active && ~sl_active
                        classificationVector(p) = TYPE_LATERAL;
                    elseif ~sa_active && sl_active
                        classificationVector(p) = TYPE_LONGITUDINAL;
                    elseif sa_active && sl_active
                        classificationVector(p) = TYPE_COMBINED;
                    else
                        classificationVector(p) = TYPE_INAKTIV;
                    end
                end

                % 4. Klassifizierung glätten (Medianfilter) ---
                smoothedClassification = medfilt1(classificationVector, filter_window);

                % 5. Dominante Phase bestimmen (KORRIGIERTE Logik) ---

                % 1. Aktive Phasen aus dem geglätteten Vektor isolieren (Ignoriere TYPE_INAKTIV = 0)
                activeClassification = smoothedClassification(smoothedClassification > TYPE_INAKTIV);

                dominant_phase_percentage = 0; % Initialisierung
                max_count = 0;

                if isempty(activeClassification)
                    % Wenn nach der Glättung nur noch INAKTIV übrig ist
                    method = 'Undefined';
                else
                    % 2. Zähle die Häufigkeiten der aktiven Phasen (1, 2, 3)
                    count_Lateral = sum(activeClassification == TYPE_LATERAL);
                    count_Longitudinal = sum(activeClassification == TYPE_LONGITUDINAL);
                    count_Combined = sum(activeClassification == TYPE_COMBINED);

                    phase_counts = [count_Lateral, count_Longitudinal, count_Combined];

                    % Finde die dominanteste aktive Phase
                    [max_count, dominant_index] = max(phase_counts);

                    % Konvertiere Index (1, 2, 3) zurück zum Phasen-Typ (1=Lat, 2=Long, 3=Comb)
                    dominant_type = dominant_index;

                    % 6. TestingMethod zuweisen ---
                    if dominant_type == TYPE_LATERAL
                        method = 'Lateral';
                    elseif dominant_type == TYPE_LONGITUDINAL
                        method = 'Longitudinal';
                    elseif dominant_type == TYPE_COMBINED
                        method = 'Combined';
                    else
                        method = 'Error: Unknown Type'; % Sollte nicht erreicht werden
                    end

                    % Prozentsatz für die Ausgabe
                    total_active_points = length(activeClassification);
                    dominant_phase_percentage = (max_count / total_active_points) * 100;
                end

                % Zuweisung des Ergebnisses
                % HINWEIS: Wir verwenden 'TestMethod' basierend auf Ihrer letzten Eingabe
                current_obj.TestMethod = method;

                % Geändertes Objekt zurückschreiben
                tireObj_out(k) = current_obj;

                % fprintf('   Datei %d/%d: TestingMethod auf "%s" gesetzt (Dominante Phase: %.1f%%)\n', ...
                %         k, length(tireObj_in), method, dominant_phase_percentage);
            end
        end

        %%  Plotten
        
        function refresh_all_plots(app)
            % REFRESH_ALL_PLOTS Zeichnet ALLE Plots der App neu. Zentrale
            % Stelle, die von Filter-, Smoothing- und manuellen
            % Auswahl-Aenderungen aufgerufen wird.
            %
            % Autor: Lambo || Datum: 08.07.26
            % Changelog:
            %   08.07.26 - Explizites cla('reset') statt cla() um Ghost-Plots
            %              zu vermeiden. Hold-State wird vor cla zurückgesetzt.

            if isempty(app.sweep)
                return
            end

            % % Alle Achsen-Handles sammeln
            % all_axes = [app.UIAxes_Sweep, app.UIAxes_Mu, app.UIAxes_Pneu, ...
            %     app.UIAxes, app.UIAxes_2, app.UIAxes_3, ...
            %     app.UIAxes_CK_1, app.UIAxes_CK_2, app.UIAxes_CK_3, ...
            %     app.UIAxes_Cam_1, app.UIAxes_Cam_2, app.UIAxes_Cam_3, ...
            %     app.UIAxes_DB_1, app.UIAxes_DB_2, app.UIAxes_DB_3, ...
            %     app.UIAxes_CS_1, app.UIAxes_CS_2, ...
            %     app.UIAxes_CH_1, app.UIAxes_CH_2, ...
            %     app.UIAxes_TR_1, app.UIAxes_TR_2, ...
            %     app.UIAxes_SV_1, app.UIAxes_SV_2, app.UIAxes_SV_3, ...
            %     app.UIAxes_RE_1, app.UIAxes_RE_2, ...
            %     app.UIAxes_MS_1, app.UIAxes_MS_2, app.UIAxes_MS_3];
            % 
            % % SICHER: Alle Achsen komplett leeren (Fix für Ghost-Plots)
            % for k = 1:numel(all_axes)
            %     ax = all_axes(k);
            %     if isvalid(ax)
            %         hold(ax, 'off');        % Hold-State zurücksetzen
            %         cla(ax, 'reset');       % Komplett leeren inkl. versteckter Objekte
            %     end
            % end

            plot_cornering_html(app)
            % app.plot_test_preprocessing();
            % app.plot_cornering_kennwerte();
            % app.plot_camber_sweep();
            % app.plot_drive_brake();
            % app.plot_combined_slip();
            % app.plot_cold_to_hot();
            % app.plot_transient();
            % app.plot_speed_vergleich();
            % app.plot_rohdaten_explorer();
            % app.plot_manual_selection();
        end

        function plot_cornering_html(app)
            % PLOT_CORNERING_HTML Baut den Datenvertrag fuer das ECharts-
            % Cornering-Widget (CorneringHTML) aus den TestMethod=='Lateral'
            % Sweeps und schreibt ihn nach app.CorneringHTML.Data. Ersetzt
            % die reine MATLAB-Plot-Funktion plot_cornering() fuer die
            % Darstellung -- Filter-/Smoothing-Backend bleibt unveraendert.
            %
            % Autor: Lambo || Datum: 08.07.26

            if isempty(app.sweep)
                return
            end
            if isempty(app.CorneringHTML) || ~isvalid(app.CorneringHTML)
                return
            end

            methods_ = [app.sweep.TestMethod];
            is_lateral = (methods_ == "Lateral");
            lateral_idx = find(is_lateral);
            lateral_sweeps = app.sweep(lateral_idx);
            if isempty(lateral_sweeps)
                return
            end
            if isempty(app.SweepIsUsed)
                app.SweepIsUsed = true(size(app.sweep));
            end

            sweeps_payload = cell(1, numel(lateral_sweeps));
            for k = 1:numel(lateral_sweeps)
                orig_idx = lateral_idx(k);
                s = lateral_sweeps(k);

                sa_deg    = rad2deg(s.alpha);
                fy_smooth = app.smooth_channel(s, 'Fy', s.Fy);
                mz_smooth = app.smooth_channel(s, 'Mz', s.Mz);
                mx_smooth = app.smooth_channel(s, 'Mx', s.Mx);

                sweeps_payload{k} = struct( ...
                    'id',        k - 1, ...                    % 0-basiert fuer JS
                    'origIndex', orig_idx, ...                  % 1-basiert, Rueckweg nach MATLAB
                    'fz',        median(s.Fz, 'omitnan'), ...
                    'used',      logical(app.SweepIsUsed(orig_idx)), ...
                    'r2',        app.r2_quality(s.Fy, fy_smooth), ...
                    'sa',        sa_deg(:)', ...
                    'raw',       struct('Fy', s.Fy(:)', 'Mz', s.Mz(:)', 'Mx', s.Mx(:)'), ...
                    'smooth',    struct('Fy', fy_smooth(:)', 'Mz', mz_smooth(:)', 'Mx', mx_smooth(:)') ...
                    );
            end

            payload = struct( ...
                'xLabel',   'Schraeglaufwinkel alpha [deg]', ...
                'channels', {{ ...
                struct('key', 'Fy', 'label', 'Fy [N]'), ...
                struct('key', 'Mz', 'label', 'Mz [Nm]'), ...
                struct('key', 'Mx', 'label', 'Mx [Nm]') ...
                }}, ...
                'sweeps',   {sweeps_payload} ...
                );

            app.CorneringHTML.Data = payload;
        end

        % Plot Helper
        function r2 = r2_quality(~, y_raw, y_smooth)
            % R2_QUALITY Einfaches Guetemass (Bestimmtheitsmass) zwischen
            % Roh- und geglaetteter Kurve, fuer die Anzeige im
            % Cornering-Widget (Sidebar-Tabelle).
            %
            % Autor: Lambo || Datum: 08.07.26

            y_raw = y_raw(:);
            y_smooth = y_smooth(:);
            valid = ~isnan(y_raw) & ~isnan(y_smooth);
            if nnz(valid) < 2
                r2 = NaN;
                return
            end
            ss_res = sum((y_raw(valid) - y_smooth(valid)).^2);
            ss_tot = sum((y_raw(valid) - mean(y_raw(valid))).^2);
            if ss_tot < eps
                r2 = 1;
            else
                r2 = 1 - ss_res / ss_tot;
            end
        end
        
        %% Filter functions
        function apply_filters(app)
            % APPLY_FILTERS Wertet alle aktiven Filterzeilen aus
            % Liest nun direkte Daten-Structs aus dem JavaScript-Frontend.

            if isempty(app.sweep)
                return
            end

            n = numel(app.sweep);
            used = true(n, 1);

            for k = 1:n
                s = app.sweep(k);
                for f = 1:numel(app.FilterRows)

                    % Unterscheidung, falls MATLAB das JS-Array als Cell-Array interpretiert
                    if iscell(app.FilterRows)
                        row = app.FilterRows{f};
                    else
                        row = app.FilterRows(f);
                    end

                    % Direkter Zugriff auf die kleingeschriebenen Variablen aus JS
                    level = app.get_sweep_level(s, row.parameter);
                    v1 = row.val1;
                    v2 = row.val2;

                    switch row.condition
                        case 'Lowerbound (>=)', ok = level >= v1;
                        case 'Upperbound (<=)', ok = level <= v1;
                        case 'Bereich [min,max]', ok = level >= v1 && level <= v2;
                        case 'Gleich (==)', ok = level == v1;
                        case 'Ungleich (!=)', ok = level ~= v1;
                        otherwise, ok = true;
                    end

                    if ~ok
                        used(k) = false;
                        break
                    end
                end
            end

            app.FilterIsUsed = used;
            app.update_combined_mask();
        end

        function level = get_sweep_level(~, sweep, parameter)
            level = median(sweep.(parameter), 'omitnan');
        end

        function update_combined_mask(app)
            % UPDATE_COMBINED_MASK Kombiniert Filter-Ergebnis (FilterIsUsed) und
            % manuellen Ausschluss (ManualExclude) zur effektiven Nutzungsmaske
            % SweepIsUsed, die von ALLEN Plot-Funktionen konsultiert wird.
            %
            % Autor: Lambo || Datum: 08.07.26

            if isempty(app.sweep)
                return
            end
            if isempty(app.FilterIsUsed)
                app.FilterIsUsed = true(size(app.sweep));
            end
            if isempty(app.ManualExclude)
                app.ManualExclude = false(size(app.sweep));
            end

            app.SweepIsUsed = app.FilterIsUsed & ~app.ManualExclude;
            app.refresh_all_plots();
        end

        %% Smoothing functions

        function x_smooth = apply_smoothing(~, x, filter_type, param1, param2, fs)
            x = x(:);
            n = numel(x);

            switch filter_type
                case 'Bessel'
                    N = round(param1);
                    fc = param2;
                    if fc <= 0 || fc >= fs/2 || n < 3*N
                        x_smooth = x;
                        return
                    end
                    Wn = 2*pi*fc;
                    [num, den] = besself(N, Wn);
                    [numd, dend] = bilinear(num, den, fs);
                    x_smooth = filtfilt(numd, dend, x);

                case 'Moving Average'
                    win = max(1, round(param1));
                    x_smooth = movmean(x, win);

                case 'Savitzky-Golay'
                    order = round(param1);
                    win = round(param2);
                    if mod(win, 2) == 0
                        win = win + 1;
                    end
                    win = max(win, order + 1 + mod(order + 1, 2));
                    if n <= win
                        x_smooth = x;
                        return
                    end
                    x_smooth = sgolayfilt(x, order, win);

                case 'Median'
                    win = round(param1);
                    if mod(win, 2) == 0
                        win = win + 1;
                    end
                    x_smooth = medfilt1(x, win);

                otherwise
                    x_smooth = x;
            end
        end

        function row = get_smoothing_row_for_parameter(app, parameter)
            row = [];
            for k = 1:numel(app.SmoothingRows)

                if iscell(app.SmoothingRows)
                    currentRow = app.SmoothingRows{k};
                else
                    currentRow = app.SmoothingRows(k);
                end

                % Zieht 'parameter' und 'active' aus dem JS-Struct
                if strcmp(currentRow.parameter, parameter) && currentRow.active
                    row = currentRow;
                    return
                end
            end
        end

        function y_out = smooth_channel(app, s, parameter, y_raw)
            row = app.get_smoothing_row_for_parameter(parameter);

            if isempty(row)
                y_out = y_raw;
                return
            end

            fs = 1 / median(diff(s.et), 'omitnan');

            % Liest 'type', 'param1' und 'param2' aus dem JS-Struct
            y_out = app.apply_smoothing(y_raw, row.type, row.param1, row.param2, fs);
        end

        %% Eigene Callbacks
        function StartupHTMLDataChanged(app, event)
            if isequal(event.Data, "start")
                delete(app.StartupHTML);   % Startup-Screen entfernen
                app.MainPanel.Visible = 'on';   % Hauptansicht einblenden – Namen ggf. anpassen
            end
        end

        function FilterSmoothingHTMLDataChanged(app, event)

            % Holt das übergebene Daten-Struct aus der HTML-Komponente
            receivedData = app.FilterSmoothingHTML.Data;

            % 1. Checkbox-Zustand auslesen
            app.HideUnusedCheckBox.Value = receivedData.hideUnused;

            % 2. Filter-Daten konvertieren und in app.FilterRows einspeisen
            % (Hier adaptierst du die Struktur auf deine bestehende Backend-Logik)
            app.FilterRows = receivedData.filters;

            % 3. Smoothing-Daten konvertieren und in app.SmoothingRows einspeisen
            app.SmoothingRows = receivedData.smoothings;

            % 4. Zentraler Berechnungs- und Plot-Aufruf (kein Auto-Update mehr!)
            apply_filters(app);
            % app.refresh_all_plots();
        end

        function CorneringHTMLEventReceived(app, event)
            % CORNERINGHTMLEVENTRECEIVED Reagiert auf Events aus dem
            % Cornering-ECharts-Widget. Aktuell relevant fuer's Backend:
            % 'sweepUsedToggled' (aendert SweepIsUsed und damit auch
            % andere Plots/Tabs). 'modeChanged' und 'hideUnusedChanged'
            % sind rein visuelle Zustaende, die das Widget selbst haelt
            % -- hier ist nichts zu tun.
            %
            % Autor: Lambo || Datum: 08.07.26

            name = event.HTMLEventName;
            data = event.HTMLEventData;

            switch name
                case 'sweepUsedToggled'
                    if isempty(app.SweepIsUsed)
                        app.SweepIsUsed = true(size(app.sweep));
                    end
                    app.SweepIsUsed(data.origIndex) = logical(data.used);

                    app.plot_cornering_html();
                    % Falls andere Tabs SweepIsUsed ebenfalls
                    % beruecksichtigen sollen, hier zusaetzlich
                    % app.refresh_all_plots() aufrufen, sobald diese
                    % Funktion wieder aktiv ist.

                otherwise
                    % modeChanged, hideUnusedChanged: kein Backend-Bezug
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Startup der App
            clc
            % addpath('html') % hier werden die HMTL functions später liegen

            % Erstellen der Javaskript Startmaske (Einblenden wenn alles
            % andere Fertig ist)
            % app.StartupHTML = uihtml(app.UIFigure);
            % app.StartupHTML.Position = [1 1 app.UIFigure.Position(3) app.UIFigure.Position(4)];
            % app.StartupHTML.HTMLSource = fullfile(pwd, 'startup_screen.html');
            % app.StartupHTML.DataChangedFcn = createCallbackFcn(app, @StartupHTMLDataChanged, true);

            % Erstellen der Javaskript Elemente für Filter und Smoothing
            app.FilterSmoothingHTML = uihtml(app.UIFigure);
            app.FilterSmoothingHTML.Position = [1 1 129 430];
            app.FilterSmoothingHTML.HTMLSource = fullfile(pwd, 'filter_smoothing.html');
            app.FilterSmoothingHTML.DataChangedFcn = createCallbackFcn(app, @FilterSmoothingHTMLDataChanged, true);

            % Erstellen der Javaskript Elemente für den Cornering Tab
            app.CorneringHTML = uihtml(app.CorneringTab_2);
            app.CorneringHTML.Position = [0 0 640 430];
            app.CorneringHTML.HTMLSource = fullfile(pwd, 'cornering_plot.html');
            app.CorneringHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @CorneringHTMLEventReceived, true);

        end

        % Button pushed function: LoadTireDataButton
        function LoadTireDataButtonPushed(app, event)

            % LOADTIREDATABUTTONPUSHED Laedt die Reifentestdaten und zeichnet
            % danach ALLE Plots ueber den zentralen refresh_all_plots()-Pfad
            % neu.
            %
            % Autor: Lambo || Datum: 07.07.26
            % Changelog:
            %   07.07.26 - Einzelne Plot-Aufrufe durch refresh_all_plots()
            %              ersetzt (inkl. neuem Sweep-Auswahl-Tab)

            load_Tire_Data(app);
            apply_filters(app);
            % app.refresh_all_plots();

        end

        % Button pushed function: OpenDirectoryButton
        function OpenDirectoryButtonPushed(app, event)

            [file,location] = uigetfile;
            tire_file_from_button = fullfile(location, file);
            app.Test_file_directroy.Value = tire_file_from_button;
       
        end

        % Callback function: not associated with a component
        function HideUnusedCheckBoxValueChanged(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.TestPreprocessingTab = uitab(app.TabGroup2);
            app.TestPreprocessingTab.Title = 'Test-Preprocessing';
            app.UIAxes_Pneu = uiaxes(app.TestPreprocessingTab);
            title(app.UIAxes_Pneu, 'Pneumatic Trail')
            xlabel(app.UIAxes_Pneu, 'Schräglaufwinkel SA [deg]')
            ylabel(app.UIAxes_Pneu, 't_p = -MZ / FY [m]')
            zlabel(app.UIAxes_Pneu, 'Z')
            app.UIAxes_Mu = uiaxes(app.TestPreprocessingTab);
            title(app.UIAxes_Mu, 'Normierte Reibwertkurve')
            xlabel(app.UIAxes_Mu, 'Schräglaufwinkel SA [deg]')
            ylabel(app.UIAxes_Mu, '\mu_y = FY / |FZ| [-]')
            zlabel(app.UIAxes_Mu, 'Z')
            app.UIAxes_Sweep = uiaxes(app.TestPreprocessingTab);
            title(app.UIAxes_Sweep, 'Rohdatenverlauf mit Sweep-Grenzen')
            xlabel(app.UIAxes_Sweep, 'Zeit ET [s]')
            ylabel(app.UIAxes_Sweep, 'Seitenkraft FY [N]')
            zlabel(app.UIAxes_Sweep, 'Z')
            app.CorneringTab_2 = uitab(app.TabGroup2);
            app.CorneringTab_2.Title = 'Cornering';
            app.CorneringKennwerteTab_2 = uitab(app.TabGroup2);
            app.CorneringKennwerteTab_2.Title = 'Cornering Kennwerte';
            app.UIAxes_CK_3 = uiaxes(app.CorneringKennwerteTab_2);
            title(app.UIAxes_CK_3, 'Cornering-Stiffness')
            xlabel(app.UIAxes_CK_3, 'FZ [N]')
            ylabel(app.UIAxes_CK_3, 'C_{Fy,\alpha} [N/deg]')
            zlabel(app.UIAxes_CK_3, 'Z')
            app.UIAxes_CK_2 = uiaxes(app.CorneringKennwerteTab_2);
            title(app.UIAxes_CK_2, 'Max. Reibwert')
            xlabel(app.UIAxes_CK_2, 'FZ [N]')
            ylabel(app.UIAxes_CK_2, '\mu_{y,max}')
            zlabel(app.UIAxes_CK_2, 'Z')
            app.UIAxes_CK_1 = uiaxes(app.CorneringKennwerteTab_2);
            title(app.UIAxes_CK_1, 'Max. Seitenkraft')
            xlabel(app.UIAxes_CK_1, 'FZ [N]')
            ylabel(app.UIAxes_CK_1, 'FY_{max} [N]')
            zlabel(app.UIAxes_CK_1, 'Z')
            app.CamberSweepTab_2 = uitab(app.TabGroup2);
            app.CamberSweepTab_2.Title = 'Camber Sweep';
            app.UIAxes_Cam_3 = uiaxes(app.CamberSweepTab_2);
            title(app.UIAxes_Cam_3, 'Sturzmoment / Schräglaufwinkel')
            xlabel(app.UIAxes_Cam_3, 'SA [deg]')
            ylabel(app.UIAxes_Cam_3, 'MX [Nm]')
            zlabel(app.UIAxes_Cam_3, 'Z')
            app.UIAxes_Cam_2 = uiaxes(app.CamberSweepTab_2);
            title(app.UIAxes_Cam_2, 'Rückstellmoment / Schräglaufwinkel')
            xlabel(app.UIAxes_Cam_2, 'SA [deg]')
            ylabel(app.UIAxes_Cam_2, 'MZ [Nm]')
            zlabel(app.UIAxes_Cam_2, 'Z')
            app.UIAxes_Cam_1 = uiaxes(app.CamberSweepTab_2);
            title(app.UIAxes_Cam_1, 'Seitenkraft / Schräglaufwinkel')
            xlabel(app.UIAxes_Cam_1, 'SA [deg]')
            ylabel(app.UIAxes_Cam_1, 'FY [N]')
            zlabel(app.UIAxes_Cam_1, 'Z')
            app.DriveBrakeTab_2 = uitab(app.TabGroup2);
            app.DriveBrakeTab_2.Title = 'Drive/Brake';
            app.UIAxes_DB_3 = uiaxes(app.DriveBrakeTab_2);
            title(app.UIAxes_DB_3, 'Seitenkraft / Längsschlupf')
            xlabel(app.UIAxes_DB_3, '\kappa [-]')
            ylabel(app.UIAxes_DB_3, 'FY [N]')
            zlabel(app.UIAxes_DB_3, 'Z')
            app.UIAxes_DB_2 = uiaxes(app.DriveBrakeTab_2);
            title(app.UIAxes_DB_2, 'Rückstellmoment / Längsschlupf')
            xlabel(app.UIAxes_DB_2, '\kappa [-]')
            ylabel(app.UIAxes_DB_2, 'MZ [Nm]')
            zlabel(app.UIAxes_DB_2, 'Z')
            app.UIAxes_DB_1 = uiaxes(app.DriveBrakeTab_2);
            title(app.UIAxes_DB_1, 'Längskraft / Längsschlupf')
            xlabel(app.UIAxes_DB_1, '\kappa [-]')
            ylabel(app.UIAxes_DB_1, 'FX [N]')
            zlabel(app.UIAxes_DB_1, 'Z')
            app.CombinedSlipTab_2 = uitab(app.TabGroup2);
            app.CombinedSlipTab_2.Title = 'Combined Slip';
            app.UIAxes_CS_2 = uiaxes(app.CombinedSlipTab_2);
            title(app.UIAxes_CS_2, 'Seitenkraft / Schräglaufwinkel (Combined)')
            xlabel(app.UIAxes_CS_2, 'SA [deg]')
            ylabel(app.UIAxes_CS_2, 'FY [N]')
            zlabel(app.UIAxes_CS_2, 'Z')
            app.UIAxes_CS_1 = uiaxes(app.CombinedSlipTab_2);
            title(app.UIAxes_CS_1, 'Längskraft / Längsschlupf (Combined)')
            xlabel(app.UIAxes_CS_1, '\kappa [-]')
            ylabel(app.UIAxes_CS_1, 'FX [N]')
            zlabel(app.UIAxes_CS_1, 'Z')
            app.ColdtohotVerschleissTab = uitab(app.TabGroup2);
            app.ColdtohotVerschleissTab.Title = 'Cold-to-hot / Verschleiss';
            app.UIAxes_CH_2 = uiaxes(app.ColdtohotVerschleissTab);
            title(app.UIAxes_CH_2, 'Cornering-Stiffness über Temperatur')
            xlabel(app.UIAxes_CH_2, 'T_{tread,C} [°C]')
            ylabel(app.UIAxes_CH_2, 'C_{Fy,\alpha} [N/deg]')
            zlabel(app.UIAxes_CH_2, 'Z')
            app.UIAxes_CH_1 = uiaxes(app.ColdtohotVerschleissTab);
            title(app.UIAxes_CH_1, '''Max. Reibwert über Temperatur''')
            xlabel(app.UIAxes_CH_1, 'T_{tread,C} [°C]')
            ylabel(app.UIAxes_CH_1, '\mu_{y,max} [-]')
            zlabel(app.UIAxes_CH_1, 'Z')
            app.TransientTab_2 = uitab(app.TabGroup2);
            app.TransientTab_2.Title = 'Transient';
            app.UIAxes_TR_2 = uiaxes(app.TransientTab_2);
            title(app.UIAxes_TR_2, 'Seitenkraft-Zeitverlauf')
            xlabel(app.UIAxes_TR_2, 'ET [s]')
            ylabel(app.UIAxes_TR_2, 'FY [N]')
            zlabel(app.UIAxes_TR_2, 'Z')
            app.UIAxes_TR_1 = uiaxes(app.TransientTab_2);
            title(app.UIAxes_TR_1, 'Schräglaufwinkel-Zeitverlauf')
            xlabel(app.UIAxes_TR_1, 'ET [s]')
            ylabel(app.UIAxes_TR_1, 'SA [deg]')
            zlabel(app.UIAxes_TR_1, 'Z')
            app.SpeedVergleichTab_2 = uitab(app.TabGroup2);
            app.SpeedVergleichTab_2.Title = 'Speed-Vergleich';
            app.UIAxes_SV_3 = uiaxes(app.SpeedVergleichTab_2);
            title(app.UIAxes_SV_3, 'Cornering-Stiffness über V')
            xlabel(app.UIAxes_SV_3, 'V [m/s]')
            ylabel(app.UIAxes_SV_3, 'C_{Fy,\alpha} [N/deg]')
            zlabel(app.UIAxes_SV_3, 'Z')
            app.UIAxes_SV_2 = uiaxes(app.SpeedVergleichTab_2);
            title(app.UIAxes_SV_2, 'Max. Reibwert über V')
            xlabel(app.UIAxes_SV_2, 'V [m/s]')
            ylabel(app.UIAxes_SV_2, '\mu_{y,max} [-]')
            zlabel(app.UIAxes_SV_2, 'Z')
            app.UIAxes_SV_1 = uiaxes(app.SpeedVergleichTab_2);
            title(app.UIAxes_SV_1, 'Max. Seitenkraft über V')
            xlabel(app.UIAxes_SV_1, 'V [m/s]')
            ylabel(app.UIAxes_SV_1, 'FY_{max} [N]')
            zlabel(app.UIAxes_SV_1, 'Z')
            app.RohdatenExplorerTab_2 = uitab(app.TabGroup2);
            app.RohdatenExplorerTab_2.Title = 'Rohdaten Explorer';
            app.UIAxes_RE_2 = uiaxes(app.RohdatenExplorerTab_2);
            title(app.UIAxes_RE_2, 'Schräglaufwinkel-Zeitverlauf')
            xlabel(app.UIAxes_RE_2, 'ET [s]')
            ylabel(app.UIAxes_RE_2, 'SA [deg]')
            zlabel(app.UIAxes_RE_2, 'Z')
            app.UIAxes_RE_1 = uiaxes(app.RohdatenExplorerTab_2);
            title(app.UIAxes_RE_1, 'Normalkraft-Zeitverlauf')
            xlabel(app.UIAxes_RE_1, 'ET [s]')
            ylabel(app.UIAxes_RE_1, 'FZ [N]')
            zlabel(app.UIAxes_RE_1, 'Z')
            app.ManuelSelctionTab = uitab(app.TabGroup2);
            app.ManuelSelctionTab.Title = 'Manuel Selction';
            app.UIAxes_MS_3 = uiaxes(app.ManuelSelctionTab);
            title(app.UIAxes_MS_3, 'Seitenkraft / Längsschlupf')
            xlabel(app.UIAxes_MS_3, '\kappa [-]')
            ylabel(app.UIAxes_MS_3, 'FY [N]')
            zlabel(app.UIAxes_MS_3, 'Z')
            app.UIAxes_MS_2 = uiaxes(app.ManuelSelctionTab);
            title(app.UIAxes_MS_2, 'Rückstellmoment / Längsschlupf')
            xlabel(app.UIAxes_MS_2, '\kappa [-]')
            ylabel(app.UIAxes_MS_2, 'MZ [Nm]')
            zlabel(app.UIAxes_MS_2, 'Z')
            app.UIAxes_MS_1 = uiaxes(app.ManuelSelctionTab);
            title(app.UIAxes_MS_1, 'Längskraft / Längsschlupf')
            xlabel(app.UIAxes_MS_1, '\kappa [-]')
            ylabel(app.UIAxes_MS_1, 'FX [N]')
            zlabel(app.UIAxes_MS_1, 'Z')
            app.ShowFilteredYellowCheckBox = uicheckbox(app.ManuelSelctionTab);
            app.ShowFilteredYellowCheckBox.Text = 'ShowFilteredYellowCheckBox';
            app.Tab = uitab(app.TabGroup2);
            app.Tab.Title = 'Tab';
            app.Panel = uipanel(app.UIFigure);
            app.DynamicseVLabel = uilabel(app.Panel);
            app.DynamicseVLabel.Text = 'Dynamics e.V.';
            app.ReifentestViewerCarNo62Label = uilabel(app.Panel);
            app.ReifentestViewerCarNo62Label.Text = 'Reifentest Viewer | Car No. 62     ';
            app.AuswahlPanel = uipanel(app.UIFigure);
            app.AuswahlPanel.Title = 'Auswahl';
            app.OpenDirectoryButton = uibutton(app.AuswahlPanel, 'push');
            app.OpenDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @OpenDirectoryButtonPushed, true);
            app.OpenDirectoryButton.Text = 'Open Directory';
            app.Test_file_directroy = uieditfield(app.AuswahlPanel, 'text');
            app.Test_file_directroy.Value = '''C:\Users\Danie\OneDrive\Desktop\Tire_Data_fitting_MF6.2\0_Tire_test_data\0_Reifen_43075\B2356run6.mat''';
            app.LoadTireDataButton = uibutton(app.AuswahlPanel, 'push');
            app.LoadTireDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTireDataButtonPushed, true);
            app.LoadTireDataButton.Text = 'Load Tire Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tire_Filter_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end